classdef DLPhonecam_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PushbuttonbelowtotakepictureLabel  matlab.ui.control.Label
        AimyourcameraatthecontentsLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PleasetakethecontentsoftheFirstAidboxoutandputthemonaplainbackgroundLabel  matlab.ui.control.Label
        FirstAidBoxContentsCheckLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PresstotakephotoButton         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        m;
        camObj;
        net;
        inputSize;
        newimg;
        CName;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net=alexnet;
            app.inputSize = app.net.Layers(1).InputSize;

            app.m = mobiledev;
            app.camObj = camera(app.m,'back');
            img = snapshot(app.camObj,'immediate');
        end

        % Callback function: PresstotakephotoButton, UIAxes
        function PresstotakephotoButtonPushed(app, event)
            app.AimyourcameraatthecontentsLabel.Visible = 'off'
            app.PleasetakethecontentsoftheFirstAidboxoutandputthemonaplainbackgroundLabel.Visible = 'off'
            app.PushbuttonbelowtotakepictureLabel.Visible = 'off'
            app.UIAxes.Visible = 'on'
            app.Label.Visible = 'on'

            img = snapshot(app.camObj,'immediate');
            imshow(img,'Parent',app.UIAxes);

            app.newimg = imresize(img, app.inputSize(1:2));
            app.CName = classify(app.net,app.newimg);

            CName0 = char(app.CName);
            x = ['I detect ' CName0];
            

            app.Label.Text = x;

            system(sprintf('say %s', x));

           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PresstotakephotoButtonPushed, true);
            app.UIAxes.Visible = 'off';
            app.PresstotakephotoButton = uibutton(app.UIFigure, 'push');
            app.PresstotakephotoButton.ButtonPushedFcn = createCallbackFcn(app, @PresstotakephotoButtonPushed, true);
            app.PresstotakephotoButton.Text = 'Press to take photo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.FirstAidBoxContentsCheckLabel = uilabel(app.UIFigure);
            app.FirstAidBoxContentsCheckLabel.Text = 'First Aid Box Contents Check';
            app.PleasetakethecontentsoftheFirstAidboxoutandputthemonaplainbackgroundLabel = uilabel(app.UIFigure);
            app.PleasetakethecontentsoftheFirstAidboxoutandputthemonaplainbackgroundLabel.Text = 'Please take the contents of the First Aid box out and put them on a plain background';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0633.jpg');
            app.AimyourcameraatthecontentsLabel = uilabel(app.UIFigure);
            app.AimyourcameraatthecontentsLabel.Text = 'Aim your camera at the contents';
            app.PushbuttonbelowtotakepictureLabel = uilabel(app.UIFigure);
            app.PushbuttonbelowtotakepictureLabel.Text = 'Push button below to take picture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLPhonecam_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end